classdef Segmenter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SegmenterUIFigure       matlab.ui.Figure
        Panel                   matlab.ui.container.Panel
        LoadimageButton         matlab.ui.control.StateButton
        C1CheckBox              matlab.ui.control.CheckBox
        C2CheckBox              matlab.ui.control.CheckBox
        C3CheckBox              matlab.ui.control.CheckBox
        LoadFileLabel           matlab.ui.control.Label
        RefineButton            matlab.ui.control.Button
        ClippingplaneLabel      matlab.ui.control.Label
        Theta                   matlab.ui.control.Slider
        LevelLabel2_5           matlab.ui.control.Label
        Phi                     matlab.ui.control.Slider
        LevelLabel2_6           matlab.ui.control.Label
        SliderR                 matlab.ui.control.Slider
        LevelLabel2_8           matlab.ui.control.Label
        PlaneCheckBox           matlab.ui.control.CheckBox
        CropButton              matlab.ui.control.Button
        FlipButton              matlab.ui.control.Button
        Slope                   matlab.ui.control.Slider
        LevelLabel2_9           matlab.ui.control.Label
        CellMaskCheckBox        matlab.ui.control.CheckBox
        SegmentDropDown         matlab.ui.control.DropDown
        SegmentCellLabel        matlab.ui.control.Label
        LoadMaskButton          matlab.ui.control.Button
        SegmentSlider           matlab.ui.control.Slider
        WatershedButton         matlab.ui.control.Button
        SegmentThresholdButton  matlab.ui.control.Button
        SaveMaskButton          matlab.ui.control.Button
        LevelLabel2             matlab.ui.control.Label
        ApproximateButton       matlab.ui.control.Button
        SegmentSigma            matlab.ui.control.Slider
        LevelLabel2_2           matlab.ui.control.Label
        SegmentRadius           matlab.ui.control.Slider
        LevelLabel2_3           matlab.ui.control.Label
        SeparateButton          matlab.ui.control.Button
        Amount                  matlab.ui.control.Slider
        LevelLabel2_4           matlab.ui.control.Label
        Brightness              matlab.ui.control.Slider
        LevelLabel2_10          matlab.ui.control.Label
        View3DButton            matlab.ui.control.Button
        LevelLabel2_11          matlab.ui.control.Label
        UIAxes                  matlab.ui.control.UIAxes
        PlaneSliderLabel        matlab.ui.control.Label
        PlaneSlider             matlab.ui.control.Slider
    end

    properties (Access = private)
        C1 % channel 1
        C2 % channel 2
        C3 % channel 3
        voxelSize %voxel size
        cellMask % binary image of the cell
        colocalization % colocalization value
        currentfile %current file
        region1
        region2
        region3
        flip
        brightness % Description
    end

    methods (Access = private)

        % Code that executes after component creation
        function initialize(app)
           app.C1 = [];
           app.C2 = [];
           app.C3 = [];
           app.cellMask = [];
           app.flip = 1;
           app.brightness = 1;
        end

        % Button pushed function: ApproximateButton
        function ApproximateButtonPushed(app, event)
            if(isempty(app.C1))
                msgbox('Load image first');
            else
                app.cellMask = approximatecell(app.C1,app.SegmentRadius.Value);
                app.showImage();
            end
        end

        % Value changed function: Brightness
        function BrightnessValueChanged(app, event)
            app.brightness = app.Brightness.Value;
            app.showImage();
        end

        % Button pushed function: CropButton
        function CropButtonPushed(app, event)
            plane = makeplane3(app.C1,app.Theta.Value,app.Phi.Value,app.SliderR.Value,app.flip);
            app.C1 = app.C1.*plane;
            app.C2 = app.C2.*plane;
            app.C3 = app.C3.*plane;
        end

        % Button pushed function: FlipButton
        function FlipButtonPushed(app, event)
            app.flip = -1*app.flip;
            app.showImage();
        end

        % Value changed function: Phi
        function PhiValueChanged(app, event)
            app.showImage();
        end

        % Value changed function: PlaneCheckBox
        function PlaneCheckBoxValueChanged(app, event)
            app.showImage();          
        end

        % Button pushed function: RefineButton
        function RefineButtonPushed(app, event)
            if(isempty(app.cellMask))
                msgbox('Calculate cell mask first');
            else
                app.cellMask = refinemask(app.cellMask,app.Amount.Value);
                app.showImage();
            end
        end

        % Button pushed function: SaveMaskButton
        function SaveMaskButtonPushed(app, event)
            if(isempty(app.cellMask))
                msgbox('Calculate cell mask first');
            else
                savetif1(app.cellMask,strcat('data/mask_',app.currentfile));
            end
        end

        % Button pushed function: SeparateButton
        function SeparateButtonPushed(app, event)
            if(isempty(app.cellMask))
                msgbox('Calculate cell mask first');
            else
                app.cellMask = separatecells(app.cellMask,app.SegmentRadius.Value,app.voxelSize);
                app.showImage();
            end
        end

        % Value changed function: SliderR
        function SliderRValueChanged(app, event)
            app.showImage();     
        end

        % Callback function
        function SliderZValueChanged(app, event)
            app.showImage();   
        end

        % Value changed function: Theta
        function ThetaValueChanged(app, event)
            app.showImage();
        end

        % Button pushed function: View3DButton
        function View3DButtonPushed(app, event)
            show3d(app.cellMask);
        end

        % Button pushed function: WatershedButton
        function WatershedButtonPushed(app, event)
             if(isempty(app.cellMask))
                msgbox('Need rough cell mask');
            else
                if(app.SegmentDropDown.Value == 'Channel 1')
                    app.cellMask = watershedcell(app.C1,app.cellMask,app.SegmentSigma.Value);
                elseif(app.SegmentDropDown.Value == 'Channel 2')
                    app.cellMask = watershedcell(app.C2,app.cellMask,app.SegmentSigma.Value);
                else
                    app.cellMask =  watershedcell(app.C3,app.cellMask,app.SegmentSigma.Value);
                end
                app.showImage();
            end
        end

        % Button pushed function: SegmentThresholdButton
        function cellThresholdButtonPushed(app, event)
            if(isempty(app.C1))
                msgbox('Load image first');
            else
                if(app.SegmentDropDown.Value == 'Channel 1')
                    app.cellMask = thresholdcell(app.C1,app.SegmentSlider.Value,app.SegmentSigma.Value,app.Slope.Value);
                elseif(app.SegmentDropDown.Value == 'Channel 2')
                    app.cellMask = thresholdcell(app.C2,app.SegmentSlider.Value,app.SegmentSigma.Value,app.Slope.Value);
                else
                    app.cellMask = thresholdcell(app.C3,app.SegmentSlider.Value,app.SegmentSigma.Value,app.Slope.Value);
                end
                app.showImage();
            end
        end

        % Value changed function: LoadimageButton
        function loadImageButtonPushed(app, event)
            filename = uigetfile('*.tif');
            app.currentfile = filename;
            [app.C1,app.C2,app.C3,app.voxelSize] = loadtif3(app.currentfile);
            app.PlaneSlider.Limits = [1 size(app.C1,3)];
            app.CellMaskCheckBox.Value = 0;
            app.cellMask = [];
            app.LoadimageButton.Value = 0;
            app.showImage();
        end

        % Button pushed function: LoadMaskButton
        function loadMaskButtonPushed(app, event)
            filename = uigetfile('*.tif');
            app.cellMask = loadtif1(filename);
            app.showImage();
        end

        % Callback function: C1CheckBox, C2CheckBox, C3CheckBox, 
        % CellMaskCheckBox, PlaneSlider
        function showImage(app, event)
            z = round(app.PlaneSlider.Value);
            if(app.C1CheckBox.Value == 1)
                R = app.C1(:,:,z)*app.brightness;
            else
                R = app.C1(:,:,1).*0;
            end
            if(app.C2CheckBox.Value == 1)
                G = app.C2(:,:,z)*app.brightness;
            else
                G = app.C2(:,:,1).*0;
            end
            if(app.C3CheckBox.Value == 1)
                B = app.C3(:,:,z)*app.brightness;
            else
                B = app.C3(:,:,1).*0;
            end
            if(app.CellMaskCheckBox.Value == 1)
                if(isempty(app.cellMask))
                    msgbox('Calculate cell mask first');
                else
                    R = max(R,bwperim(app.cellMask(:,:,z)));
                    G = max(G,bwperim(app.cellMask(:,:,z)));
                    B = max(B,bwperim(app.cellMask(:,:,z)));
                end
            end
            if(app.PlaneCheckBox.Value == 1)
                plane = makeplane2(app.C1,z,app.Theta.Value,app.Phi.Value,app.SliderR.Value,app.flip);
                R = R + plane;
                G = G + plane;
                B = B + plane;
            end
            image(app.UIAxes,cat(3,R,G,B));
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SegmenterUIFigure = uifigure;
            app.SegmenterUIFigure.Name = 'Segmenter';
            app.Panel = uipanel(app.SegmenterUIFigure);
            app.LoadimageButton = uibutton(app.Panel, 'state');
            app.LoadimageButton.ValueChangedFcn = createCallbackFcn(app, @loadImageButtonPushed, true);
            app.LoadimageButton.Text = 'Load image';
            app.C1CheckBox = uicheckbox(app.Panel);
            app.C1CheckBox.ValueChangedFcn = createCallbackFcn(app, @showImage, true);
            app.C1CheckBox.Text = 'C1';
            app.C1CheckBox.Value = true;
            app.C2CheckBox = uicheckbox(app.Panel);
            app.C2CheckBox.ValueChangedFcn = createCallbackFcn(app, @showImage, true);
            app.C2CheckBox.Text = 'C2';
            app.C2CheckBox.Value = true;
            app.C3CheckBox = uicheckbox(app.Panel);
            app.C3CheckBox.ValueChangedFcn = createCallbackFcn(app, @showImage, true);
            app.C3CheckBox.Text = 'C3';
            app.C3CheckBox.Value = true;
            app.LoadFileLabel = uilabel(app.Panel);
            app.LoadFileLabel.Text = 'Load File';
            app.RefineButton = uibutton(app.Panel, 'push');
            app.RefineButton.ButtonPushedFcn = createCallbackFcn(app, @RefineButtonPushed, true);
            app.RefineButton.Text = 'Refine';
            app.ClippingplaneLabel = uilabel(app.Panel);
            app.ClippingplaneLabel.Text = 'Clipping plane';
            app.Theta = uislider(app.Panel);
            app.Theta.Limits = [0 3.1415];
            app.Theta.MajorTicks = [];
            app.Theta.MajorTickLabels = {};
            app.Theta.ValueChangedFcn = createCallbackFcn(app, @ThetaValueChanged, true);
            app.Theta.MinorTicks = [];
            app.Theta.Value = 1.5707;
            app.LevelLabel2_5 = uilabel(app.Panel);
            app.LevelLabel2_5.Text = 'θ';
            app.Phi = uislider(app.Panel);
            app.Phi.Limits = [0 6.283];
            app.Phi.MajorTicks = [];
            app.Phi.MajorTickLabels = {};
            app.Phi.ValueChangedFcn = createCallbackFcn(app, @PhiValueChanged, true);
            app.Phi.MinorTicks = [];
            app.Phi.Value = 3.1415;
            app.LevelLabel2_6 = uilabel(app.Panel);
            app.LevelLabel2_6.Text = 'φ';
            app.SliderR = uislider(app.Panel);
            app.SliderR.Limits = [-200 200];
            app.SliderR.MajorTicks = [];
            app.SliderR.MajorTickLabels = {};
            app.SliderR.ValueChangedFcn = createCallbackFcn(app, @SliderRValueChanged, true);
            app.SliderR.MinorTicks = [];
            app.SliderR.Value = 1;
            app.LevelLabel2_8 = uilabel(app.Panel);
            app.LevelLabel2_8.Text = 'R';
            app.PlaneCheckBox = uicheckbox(app.Panel);
            app.PlaneCheckBox.ValueChangedFcn = createCallbackFcn(app, @PlaneCheckBoxValueChanged, true);
            app.PlaneCheckBox.Text = 'Plane';
            app.CropButton = uibutton(app.Panel, 'push');
            app.CropButton.ButtonPushedFcn = createCallbackFcn(app, @CropButtonPushed, true);
            app.CropButton.Text = 'Crop';
            app.FlipButton = uibutton(app.Panel, 'push');
            app.FlipButton.ButtonPushedFcn = createCallbackFcn(app, @FlipButtonPushed, true);
            app.FlipButton.Text = 'Flip';
            app.Slope = uislider(app.Panel);
            app.Slope.Limits = [-0.05 0.05];
            app.Slope.MajorTicks = [];
            app.Slope.MajorTickLabels = {};
            app.Slope.MinorTicks = [];
            app.LevelLabel2_9 = uilabel(app.Panel);
            app.LevelLabel2_9.Text = 'Slope';
            app.CellMaskCheckBox = uicheckbox(app.Panel);
            app.CellMaskCheckBox.ValueChangedFcn = createCallbackFcn(app, @showImage, true);
            app.CellMaskCheckBox.Text = 'Cell Mask';
            app.SegmentDropDown = uidropdown(app.Panel);
            app.SegmentDropDown.Items = {'Channel 1', 'Channel 2', 'Channel 3'};
            app.SegmentDropDown.Value = 'Channel 1';
            app.SegmentCellLabel = uilabel(app.Panel);
            app.SegmentCellLabel.Text = 'Segment Cell';
            app.LoadMaskButton = uibutton(app.Panel, 'push');
            app.LoadMaskButton.ButtonPushedFcn = createCallbackFcn(app, @loadMaskButtonPushed, true);
            app.LoadMaskButton.Text = 'Load Mask';
            app.SegmentSlider = uislider(app.Panel);
            app.SegmentSlider.Limits = [0 0.7];
            app.SegmentSlider.MajorTicks = [];
            app.SegmentSlider.MajorTickLabels = {};
            app.SegmentSlider.MinorTicks = [];
            app.SegmentSlider.Value = 0.35;
            app.WatershedButton = uibutton(app.Panel, 'push');
            app.WatershedButton.ButtonPushedFcn = createCallbackFcn(app, @WatershedButtonPushed, true);
            app.WatershedButton.Text = 'Watershed';
            app.SegmentThresholdButton = uibutton(app.Panel, 'push');
            app.SegmentThresholdButton.ButtonPushedFcn = createCallbackFcn(app, @cellThresholdButtonPushed, true);
            app.SegmentThresholdButton.Text = 'Threshold';
            app.SaveMaskButton = uibutton(app.Panel, 'push');
            app.SaveMaskButton.ButtonPushedFcn = createCallbackFcn(app, @SaveMaskButtonPushed, true);
            app.SaveMaskButton.Text = 'Save Mask';
            app.LevelLabel2 = uilabel(app.Panel);
            app.LevelLabel2.Text = 'Level';
            app.ApproximateButton = uibutton(app.Panel, 'push');
            app.ApproximateButton.ButtonPushedFcn = createCallbackFcn(app, @ApproximateButtonPushed, true);
            app.ApproximateButton.Text = 'Approximate';
            app.SegmentSigma = uislider(app.Panel);
            app.SegmentSigma.Limits = [0 10];
            app.SegmentSigma.MajorTicks = [];
            app.SegmentSigma.MajorTickLabels = {};
            app.SegmentSigma.MinorTicks = [];
            app.SegmentSigma.Value = 5;
            app.LevelLabel2_2 = uilabel(app.Panel);
            app.LevelLabel2_2.Text = 'Sigma';
            app.SegmentRadius = uislider(app.Panel);
            app.SegmentRadius.Limits = [0 1];
            app.SegmentRadius.MajorTicks = [];
            app.SegmentRadius.MajorTickLabels = {};
            app.SegmentRadius.MinorTicks = [];
            app.SegmentRadius.Value = 0.5;
            app.LevelLabel2_3 = uilabel(app.Panel);
            app.LevelLabel2_3.Text = 'Radius';
            app.SeparateButton = uibutton(app.Panel, 'push');
            app.SeparateButton.ButtonPushedFcn = createCallbackFcn(app, @SeparateButtonPushed, true);
            app.SeparateButton.Text = 'Separate';
            app.Amount = uislider(app.Panel);
            app.Amount.Limits = [-5 5];
            app.Amount.MajorTicks = [];
            app.Amount.MajorTickLabels = {};
            app.Amount.MinorTicks = [];
            app.LevelLabel2_4 = uilabel(app.Panel);
            app.LevelLabel2_4.Text = 'Amount';
            app.Brightness = uislider(app.Panel);
            app.Brightness.Limits = [0 4];
            app.Brightness.MajorTicks = [];
            app.Brightness.MajorTickLabels = {};
            app.Brightness.ValueChangedFcn = createCallbackFcn(app, @BrightnessValueChanged, true);
            app.Brightness.MinorTicks = [];
            app.Brightness.Value = 1;
            app.LevelLabel2_10 = uilabel(app.Panel);
            app.LevelLabel2_10.Text = 'Brightness';
            app.View3DButton = uibutton(app.Panel, 'push');
            app.View3DButton.ButtonPushedFcn = createCallbackFcn(app, @View3DButtonPushed, true);
            app.View3DButton.Text = 'View 3D';
            app.LevelLabel2_11 = uilabel(app.Panel);
            app.LevelLabel2_11.Text = '0';
            app.UIAxes = uiaxes(app.SegmenterUIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.PlaneSliderLabel = uilabel(app.SegmenterUIFigure);
            app.PlaneSliderLabel.Text = 'Plane';
            app.PlaneSlider = uislider(app.SegmenterUIFigure);
            app.PlaneSlider.MajorTicks = [];
            app.PlaneSlider.MajorTickLabels = {};
            app.PlaneSlider.ValueChangedFcn = createCallbackFcn(app, @showImage, true);
            app.PlaneSlider.ValueChangingFcn = createCallbackFcn(app, @showImage, true);
            app.PlaneSlider.MinorTicks = [];
        end
    end

    methods (Access = public)

        % Construct app
        function app = Segmenter

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SegmenterUIFigure)

            % Execute the startup function
            runStartupFcn(app, @initialize)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SegmenterUIFigure)
        end
    end
end